classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TextoInform                   matlab.ui.control.TextArea
        GuardaclassificaoLabel        matlab.ui.control.Label
        Fichiro_Guarda_Classificacao  matlab.ui.control.EditField
        GuardaClassificaoButton       matlab.ui.control.Button
        TreinarredeButton             matlab.ui.control.Button
        TestarredeguardadaButton      matlab.ui.control.Button
        PastaausarButtonGroup         matlab.ui.container.ButtonGroup
        Pasta1Button                  matlab.ui.control.RadioButton
        Pasta2Button                  matlab.ui.control.RadioButton
        Pasta3Button                  matlab.ui.control.RadioButton
        Pasta123                      matlab.ui.control.RadioButton
        InserirnomedoficheiroquequercarregarLabel  matlab.ui.control.Label
        EditFieldFicheiroLoad         matlab.ui.control.EditField
        CarregarredeButton            matlab.ui.control.Button
        GuardarredeanteriorButton     matlab.ui.control.Button
        EditFieldFicheiroSave         matlab.ui.control.EditField
        InserirnomedoficheiroemquevaiguardarLabel  matlab.ui.control.Label
        ClassificarImagemButton       matlab.ui.control.Button
        Imagem_a_Classificar          matlab.ui.control.EditField
        InsiraonomedaimagemLabel      matlab.ui.control.Label
        DesenharButton                matlab.ui.control.Button
        GuardarResultados             matlab.ui.control.Button
        NomeFichResultados            matlab.ui.control.EditField
        InsiranomedoficheroparaguardarosresultadosLabel  matlab.ui.control.Label
        PrecisoTotalTextAreaLabel     matlab.ui.control.Label
        PrecisoTotalTextArea          matlab.ui.control.TextArea
        PrecisodeTesteTextAreaLabel   matlab.ui.control.Label
        PrecisodeTesteTextArea        matlab.ui.control.TextArea
        ClassificaoLabel              matlab.ui.control.Label
        classificaoField              matlab.ui.control.EditField
        TopologiaEditFieldLabel       matlab.ui.control.Label
        TopologiaEditField            matlab.ui.control.EditField
        FunodeativaoDropDownLabel     matlab.ui.control.Label
        FunodeativaoDropDown          matlab.ui.control.DropDown
        FunodetreinoDropDownLabel     matlab.ui.control.Label
        FunodetreinoDropDown          matlab.ui.control.DropDown
        TestedeRedesNeuronaiseclassificaodeImagensLabel  matlab.ui.control.Label
        TrainRatioLabel               matlab.ui.control.Label
        TrainRatio                    matlab.ui.control.NumericEditField
        ValRatioEditFieldLabel        matlab.ui.control.Label
        ValRatio                      matlab.ui.control.NumericEditField
        TesteRatioEditFieldLabel      matlab.ui.control.Label
        TesteRatio                    matlab.ui.control.NumericEditField
        PlotConfusionSwitchLabel      matlab.ui.control.Label
        PlotConfusionSwitch           matlab.ui.control.Switch
        Lamp                          matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        net_atual %variável que tem o net atual
        tr_atual % tr atual
        accTot_atual % Precisao total
        accTes_atual % Precisao teste
        flag = 0 % flag para saber se pode fazer
        classificacao % ultima classificação obtida
        NomDaImagemUsada % Nome do ficheiro onde está a imagem usada para classificar
        Pasta % Dataset de imagens em uso
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TreinarredeButton
        function TreinarredeButtonPushed(app, event)
            if(app.Pasta1Button.Value)
                app.Pasta = 'Pasta1';
            elseif(app.Pasta2Button.Value)
                app.Pasta = 'Pasta2';
            elseif (app.Pasta3Button.Value)
                app.Pasta = 'Pasta3';
            else
                app.Pasta = 'Pasta123';
            end
            if (app.TopologiaEditField.Value == "")
                app.TopologiaEditField.Value = '10';
            end
            if (app.TrainRatio.Value + app.ValRatio.Value + app.TesteRatio.Value ~= 1)
                app.TrainRatio.Value = 0.7;
                app.ValRatio.Value = 0.15;
                app.TesteRatio.Value = 0.15;
            end
            matriz_top = str2num(app.TopologiaEditField.Value);
            [app.net_atual, app.tr_atual, app.accTot_atual, app.accTes_atual] = redes_neuronais_b_GUI(app.FunodetreinoDropDown.Value, app.FunodeativaoDropDown.Value, matriz_top, app.Pasta, app.TrainRatio.Value, app.ValRatio.Value, app.TesteRatio.Value, app.PlotConfusionSwitch.Value);
            app.PrecisoTotalTextArea.Value = num2str(app.accTot_atual);
            app.PrecisodeTesteTextArea.Value = num2str(app.accTes_atual);
            app.flag = 1;
            app.TextoInform.FontColor = 'b';
            app.TextoInform.Value = 'Rede treinada com sucesso';
            
        end

        % Button pushed function: GuardarredeanteriorButton
        function GuardarredeanteriorButtonPushed(app, event)
             if(app.EditFieldFicheiroSave.Value == "")
                 app.TextoInform.FontColor = 'r';
                 app.TextoInform.Value = 'Insira o nome do ficheiro em que quer salvar';
             elseif (app.flag == 0)
                 app.TextoInform.FontColor = 'r';
                 app.TextoInform.Value = 'Sem rede para guardar';
             else
                 Save_do_net(app.EditFieldFicheiroSave.Value, app.net_atual, app.tr_atual);
                 app.EditFieldFicheiroSave.Value = "";
                 app.TextoInform.FontColor = 'b';
                 app.TextoInform.Value = 'Rede guardada com sucesso';
             end
             % CONTINUAR AQUI COM O SAVE E O LOAD
        end

        % Button pushed function: CarregarredeButton
        function CarregarredeButtonPushed(app, event)
             if(app.EditFieldFicheiroLoad.Value == "")
                 app.TextoInform.FontColor = 'r';
                 app.TextoInform.Value = 'Insira o nome do ficheiro que quer carregar';
             else
                [app.net_atual, app.tr_atual] = Load_do_net(app.EditFieldFicheiroLoad.Value);
                app.flag = 1;
                app.EditFieldFicheiroLoad.Value = "";
                app.TextoInform.FontColor = 'b';
                app.TextoInform.Value = 'Rede carregada com sucesso';
             end
        end

        % Button pushed function: GuardarResultados
        function GuardarResultadosButtonPushed(app, event)
            if(app.NomeFichResultados.Value == "")
                app.TextoInform.FontColor = 'r';
                 app.TextoInform.Value = 'Insira o nome do ficheiro em que quer guradar os resultados';
            elseif (app.flag == 0)
                app.TextoInform.FontColor = 'r';
                 app.TextoInform.Value = 'Sem rede guaradada';
             else
                Guarda_Resultados(app.NomeFichResultados.Value, app.accTot_atual, app.accTes_atual);
                app.NomeFichResultados.Value = "";
                app.TextoInform.FontColor = 'b';
                app.TextoInform.Value = 'Resultados guardados com sucesso';
             end
        end

        % Button pushed function: TestarredeguardadaButton
        function TestarredeguardadaButtonPushed(app, event)
            if(app.Pasta1Button.Value)
                app.Pasta = 'Pasta1';
            elseif(app.Pasta2Button.Value)
                app.Pasta = 'Pasta2';
            elseif (app.Pasta3Button.Value)
                app.Pasta = 'Pasta3';
            else
                app.Pasta = 'Pasta123';
            end
            if (app.flag == 0)
                app.TextoInform.FontColor = 'r';
                app.TextoInform.Value = 'Sem rede guardada';
            else
            acc_t = redes_neuronais_c_GUI(app.net_atual, app.tr_atual, app.Pasta, app.PlotConfusionSwitch.Value);
            app.PrecisoTotalTextArea.Value = num2str(acc_t);
            app.PrecisodeTesteTextArea.Value = '0';
            app.TextoInform.FontColor = 'b';
            app.TextoInform.Value = 'Rede testada com sucesso';
            end
        end

        % Button pushed function: DesenharButton
        function DesenharButtonPushed(app, event)
            desenhar_letra();
        end

        % Button pushed function: ClassificarImagemButton
        function ClassificarImagemButtonPushed(app, event)
            if(app.Imagem_a_Classificar.Value == "")
                app.TextoInform.FontColor = 'r';
                app.TextoInform.Value = 'Insira o nome do ficheiro onde está a imagem';
            elseif (app.flag == 0)
                app.TextoInform.FontColor = 'r';
                app.TextoInform.Value = 'Sem rede guaradada';
            else
               app.classificacao = Classificar_Imagem(app.net_atual, app.Imagem_a_Classificar.Value);
               app.NomDaImagemUsada = app.Imagem_a_Classificar.Value;
               app.classificaoField.Value = app.classificacao;
               app.Imagem_a_Classificar.Value = "";
               app.TextoInform.FontColor = 'b';
               app.TextoInform.Value = 'Imagem classificada com sucesso';
            end
        end

        % Button pushed function: GuardaClassificaoButton
        function GuardaClassificaoButtonPushed(app, event)
            if(app.classificacao == "")
                app.TextoInform.FontColor = 'r';
                app.TextoInform.Value = 'Sem imagem avaliada';
            elseif (app.Fichiro_Guarda_Classificacao.Value == "")
                app.TextoInform.FontColor = 'r';
                app.TextoInform.Value = 'Insira o nome do ficheiro onde quer guardar a imagem';
            else
                 Guarda_Classificacao(app.Fichiro_Guarda_Classificacao.Value, app.NomDaImagemUsada, app.classificacao);
                 app.Fichiro_Guarda_Classificacao.Value = "";
                 app.TextoInform.FontColor = 'b';
                 app.TextoInform.Value = 'Classificação guardada com sucesso';
            end
        end

        % Value changed function: PlotConfusionSwitch
        function PlotConfusionSwitchValueChanged(app, event)
            value = app.PlotConfusionSwitch.Value;
            
            if (strcmp(value,'On'))
                app.Lamp.Color = 'g';
            else
                app.Lamp.Color = 'r';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextoInform = uitextarea(app.UIFigure);
            app.TextoInform.Editable = 'off';
            app.TextoInform.Tooltip = {''};
            app.GuardaclassificaoLabel = uilabel(app.UIFigure);
            app.GuardaclassificaoLabel.Text = 'Guarda classificação';
            app.Fichiro_Guarda_Classificacao = uieditfield(app.UIFigure, 'text');
            app.GuardaClassificaoButton = uibutton(app.UIFigure, 'push');
            app.GuardaClassificaoButton.ButtonPushedFcn = createCallbackFcn(app, @GuardaClassificaoButtonPushed, true);
            app.GuardaClassificaoButton.Text = 'Guarda Classificação';
            app.TreinarredeButton = uibutton(app.UIFigure, 'push');
            app.TreinarredeButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarredeButtonPushed, true);
            app.TreinarredeButton.Tooltip = {''};
            app.TreinarredeButton.Text = 'Treinar rede';
            app.TestarredeguardadaButton = uibutton(app.UIFigure, 'push');
            app.TestarredeguardadaButton.ButtonPushedFcn = createCallbackFcn(app, @TestarredeguardadaButtonPushed, true);
            app.TestarredeguardadaButton.Text = 'Testar rede guardada';
            app.PastaausarButtonGroup = uibuttongroup(app.UIFigure);
            app.PastaausarButtonGroup.Title = 'Pasta a usar';
            app.Pasta1Button = uiradiobutton(app.PastaausarButtonGroup);
            app.Pasta1Button.Text = 'Pasta1';
            app.Pasta1Button.Value = true;
            app.Pasta2Button = uiradiobutton(app.PastaausarButtonGroup);
            app.Pasta2Button.Text = 'Pasta2';
            app.Pasta3Button = uiradiobutton(app.PastaausarButtonGroup);
            app.Pasta3Button.Text = 'Pasta3';
            app.Pasta123 = uiradiobutton(app.PastaausarButtonGroup);
            app.Pasta123.Text = 'Pasta1 + Pasta2 + Pasta3';
            app.InserirnomedoficheiroquequercarregarLabel = uilabel(app.UIFigure);
            app.InserirnomedoficheiroquequercarregarLabel.Text = 'Inserir nome do ficheiro que quer carregar';
            app.EditFieldFicheiroLoad = uieditfield(app.UIFigure, 'text');
            app.CarregarredeButton = uibutton(app.UIFigure, 'push');
            app.CarregarredeButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarredeButtonPushed, true);
            app.CarregarredeButton.Text = 'Carregar rede';
            app.GuardarredeanteriorButton = uibutton(app.UIFigure, 'push');
            app.GuardarredeanteriorButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarredeanteriorButtonPushed, true);
            app.GuardarredeanteriorButton.Text = 'Guardar rede anterior';
            app.EditFieldFicheiroSave = uieditfield(app.UIFigure, 'text');
            app.InserirnomedoficheiroemquevaiguardarLabel = uilabel(app.UIFigure);
            app.InserirnomedoficheiroemquevaiguardarLabel.Text = 'Inserir nome do ficheiro em que vai guardar';
            app.ClassificarImagemButton = uibutton(app.UIFigure, 'push');
            app.ClassificarImagemButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificarImagemButtonPushed, true);
            app.ClassificarImagemButton.Text = 'Classificar Imagem';
            app.Imagem_a_Classificar = uieditfield(app.UIFigure, 'text');
            app.InsiraonomedaimagemLabel = uilabel(app.UIFigure);
            app.InsiraonomedaimagemLabel.Text = 'Insira o nome da imagem';
            app.DesenharButton = uibutton(app.UIFigure, 'push');
            app.DesenharButton.ButtonPushedFcn = createCallbackFcn(app, @DesenharButtonPushed, true);
            app.DesenharButton.Text = 'Desenhar';
            app.GuardarResultados = uibutton(app.UIFigure, 'push');
            app.GuardarResultados.ButtonPushedFcn = createCallbackFcn(app, @GuardarResultadosButtonPushed, true);
            app.GuardarResultados.Text = 'Guardar';
            app.NomeFichResultados = uieditfield(app.UIFigure, 'text');
            app.InsiranomedoficheroparaguardarosresultadosLabel = uilabel(app.UIFigure);
            app.InsiranomedoficheroparaguardarosresultadosLabel.Text = 'Insira nome do fichero para guardar os resultados';
            app.PrecisoTotalTextAreaLabel = uilabel(app.UIFigure);
            app.PrecisoTotalTextAreaLabel.Text = 'Precisão Total:';
            app.PrecisoTotalTextArea = uitextarea(app.UIFigure);
            app.PrecisoTotalTextArea.Editable = 'off';
            app.PrecisoTotalTextArea.Value = {'0'};
            app.PrecisodeTesteTextAreaLabel = uilabel(app.UIFigure);
            app.PrecisodeTesteTextAreaLabel.Text = 'Precisão de Teste:';
            app.PrecisodeTesteTextArea = uitextarea(app.UIFigure);
            app.PrecisodeTesteTextArea.Editable = 'off';
            app.PrecisodeTesteTextArea.Value = {'0'};
            app.ClassificaoLabel = uilabel(app.UIFigure);
            app.ClassificaoLabel.Text = 'Classificação:';
            app.classificaoField = uieditfield(app.UIFigure, 'text');
            app.classificaoField.Editable = 'off';
            app.TopologiaEditFieldLabel = uilabel(app.UIFigure);
            app.TopologiaEditFieldLabel.Text = 'Topologia';
            app.TopologiaEditField = uieditfield(app.UIFigure, 'text');
            app.FunodeativaoDropDownLabel = uilabel(app.UIFigure);
            app.FunodeativaoDropDownLabel.Text = 'Função de ativação';
            app.FunodeativaoDropDown = uidropdown(app.UIFigure);
            app.FunodeativaoDropDown.Items = {'tansig', 'compet', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'softmax', 'tribas', 'hardlim', 'hardlims', 'logsig', 'netinv'};
            app.FunodeativaoDropDown.Value = 'tansig';
            app.FunodetreinoDropDownLabel = uilabel(app.UIFigure);
            app.FunodetreinoDropDownLabel.Text = 'Função de treino';
            app.FunodetreinoDropDown = uidropdown(app.UIFigure);
            app.FunodetreinoDropDown.Items = {'traincgb', 'trainbfg', 'trainbfgc', 'trainbr', 'trainbu', 'trainc', 'traincgf', 'traincgp', 'traingd', 'traingda', 'traingdm', 'traingdx', 'trainoss', 'trainr', 'trainrp', 'trainru', 'trains', 'trainscg'};
            app.FunodetreinoDropDown.Value = 'traincgb';
            app.TestedeRedesNeuronaiseclassificaodeImagensLabel = uilabel(app.UIFigure);
            app.TestedeRedesNeuronaiseclassificaodeImagensLabel.Text = 'Teste de Redes Neuronais e classificação de Imagens';
            app.TrainRatioLabel = uilabel(app.UIFigure);
            app.TrainRatioLabel.Text = 'Train Ratio';
            app.TrainRatio = uieditfield(app.UIFigure, 'numeric');
            app.ValRatioEditFieldLabel = uilabel(app.UIFigure);
            app.ValRatioEditFieldLabel.Text = 'Val Ratio';
            app.ValRatio = uieditfield(app.UIFigure, 'numeric');
            app.TesteRatioEditFieldLabel = uilabel(app.UIFigure);
            app.TesteRatioEditFieldLabel.Text = 'Teste Ratio';
            app.TesteRatio = uieditfield(app.UIFigure, 'numeric');
            app.PlotConfusionSwitchLabel = uilabel(app.UIFigure);
            app.PlotConfusionSwitchLabel.Text = 'Plot Confusion';
            app.PlotConfusionSwitch = uiswitch(app.UIFigure, 'slider');
            app.PlotConfusionSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotConfusionSwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end